classdef app23003 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Menu                    matlab.ui.container.Menu
        Menu2                   matlab.ui.container.Menu
        LampLabel               matlab.ui.control.Label
        Lamp                    matlab.ui.control.Lamp
        TEMPERATURESliderLabel  matlab.ui.control.Label
        TEMPERATURESlider       matlab.ui.control.Slider
        GaugeLabel              matlab.ui.control.Label
        Gauge                   matlab.ui.control.Gauge
        SpinnerLabel            matlab.ui.control.Label
        Spinner                 matlab.ui.control.Spinner
    end

    methods (Access = private)

        % Value changed function: TEMPERATURESlider
        function TEMPERATURESliderValueChanged(app, event)
            app.Gauge.Value = ((app.TEMPERATURESlider.Value)*9/5)+32;
            if(app.Gauge.Value>80)
               app.Lamp.Enable=1;    
         app.Lamp.Color=[0.15,0.15,0.15];
       
          
            else 
                app.Lamp.Enable=0;
                 app.Lamp.Color='g';
            end
             app.Spinner.Value=app.Gauge.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp.Tooltip = {'k'};
            app.TEMPERATURESliderLabel = uilabel(app.UIFigure);
            app.TEMPERATURESliderLabel.Text = 'TEMPERATURE';
            app.TEMPERATURESlider = uislider(app.UIFigure);
            app.TEMPERATURESlider.ValueChangedFcn = createCallbackFcn(app, @TEMPERATURESliderValueChanged, true);
            app.GaugeLabel = uilabel(app.UIFigure);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.UIFigure, 'circular');
            app.SpinnerLabel = uilabel(app.UIFigure);
            app.SpinnerLabel.Text = 'Spinner';
            app.Spinner = uispinner(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = app23003

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end