classdef cro3003 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        amplitudeSliderLabel  matlab.ui.control.Label
        amplitudeSlider       matlab.ui.control.Slider
        frequencySliderLabel  matlab.ui.control.Label
        frequencySlider       matlab.ui.control.Slider
        UIAxes                matlab.ui.control.UIAxes
        Button                matlab.ui.control.Button
        CROLabel              matlab.ui.control.Label
        DropDownLabel         matlab.ui.control.Label
        DropDown              matlab.ui.control.DropDown
    end

    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            ax=app.UIAxes;
            t=0:0.01:5*pi;
            
            if isequal(app.DropDown.Value,'sine')
                a=app.amplitudeSlider.Value*sin(2*pi*app.frequencySlider.Value*t);
            else
        a=app.amplitudeSlider.Value*square(2*pi*app.frequencySlider.Value*t);
         end
                plot(ax,t,a)
           
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.amplitudeSliderLabel = uilabel(app.UIFigure);
            app.amplitudeSliderLabel.Text = 'amplitude';
            app.amplitudeSlider = uislider(app.UIFigure);
            app.amplitudeSlider.Limits = [0 10];
            app.frequencySliderLabel = uilabel(app.UIFigure);
            app.frequencySliderLabel.Text = 'frequency';
            app.frequencySlider = uislider(app.UIFigure);
            app.frequencySlider.Limits = [0 4];
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'time')
            ylabel(app.UIAxes, 'amplitude')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.CROLabel = uilabel(app.UIFigure);
            app.CROLabel.Text = 'CRO';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'DropDown';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'sine', 'square'};
            app.DropDown.Value = 'square';
        end
    end

    methods (Access = public)

        % Construct app
        function app = cro3003

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end